classdef ImagePanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CodageSoruceImageUIFigure      matlab.ui.Figure
        IlyasNhasseMohammedHandaLabel  matlab.ui.control.Label
        EnsaFes20222023AllrightsreservedLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        longestZerosSequenceText       matlab.ui.control.EditField
        Thelongestsequenceof0obtainedLabel  matlab.ui.control.Label
        LectenZigzagButton             matlab.ui.control.Button
        LectVerticalButton             matlab.ui.control.Button
        LectHorizontalButton           matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
        imageStatus                    matlab.ui.control.Label
        InsertaPictureButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        rawImage % Raw image data selected by the user
        squareImage % Image data enocded vertically, horizontally, or in zigzag
        vectorImage % Description
        longestZerosSequence % Description
    end
    
    methods (Access = private)
        
        function updateVectorOutput(app)
            app.longestZerosSequence = longestZero(app.vectorImage);
            app.TextArea.Value = strjoin(string(app.vectorImage));
            app.longestZerosSequenceText.Value = string(app.longestZerosSequence);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertaPictureButton
        function InsertaPictureButtonPushed(app, event)
            % Open a file dialog to choose an image file
            [filename, filepath] = uigetfile({'*.jpg;*.png;', 'Image Files (*.jpg, *.png)'});

            % Check if a file was selected
            if ischar(filename)
                % Load the selected image
                imageFilePath = fullfile(filepath, filename);
                app.rawImage = imread(imageFilePath);

                % prepare the raw image (crop it to a square)
                [originalShape, croppedShape, croppedImage] =  prepareImage(app.rawImage);
                app.squareImage = croppedImage;

                if originalShape == croppedShape
                    app.imageStatus.Text = "Inserted image is a square";
                else
                    app.imageStatus.Text = "Inserted image was cropped to a square";
                end

            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Home
            close(app.CodageSoruceImageUIFigure);
        end

        % Callback function
        function ENCODEButtonPushed(app, event)
                imshow(app.rawImage);
        end

        % Button pushed function: LectHorizontalButton
        function LectHorizontalButtonPushed(app, event)
            app.vectorImage = horiRead(app.squareImage);
            app.updateVectorOutput();
        end

        % Button pushed function: LectenZigzagButton
        function LectenZigzagButtonPushed(app, event)
            app.vectorImage = zigzagRead(app.squareImage);
            app.updateVectorOutput();
        end

        % Button pushed function: LectVerticalButton
        function LectVerticalButtonPushed(app, event)
            app.vectorImage = vertRead(app.squareImage);
            app.updateVectorOutput();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CodageSoruceImageUIFigure = uifigure('Visible', 'off');
            app.CodageSoruceImageUIFigure.Name = 'Codage Soruce Image';
            app.InsertaPictureButton = uibutton(app.CodageSoruceImageUIFigure, 'push');
            app.InsertaPictureButton.ButtonPushedFcn = createCallbackFcn(app, @InsertaPictureButtonPushed, true);
            app.InsertaPictureButton.Text = 'Insert a Picture';
            app.imageStatus = uilabel(app.CodageSoruceImageUIFigure);
            app.imageStatus.Text = 'Insert your Picture';
            app.BackButton = uibutton(app.CodageSoruceImageUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = 'icon1.png';
            app.BackButton.Text = 'Back';
            app.LectHorizontalButton = uibutton(app.CodageSoruceImageUIFigure, 'push');
            app.LectHorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @LectHorizontalButtonPushed, true);
            app.LectHorizontalButton.Text = 'Lect Horizontal';
            app.LectVerticalButton = uibutton(app.CodageSoruceImageUIFigure, 'push');
            app.LectVerticalButton.ButtonPushedFcn = createCallbackFcn(app, @LectVerticalButtonPushed, true);
            app.LectVerticalButton.Text = 'Lect Vertical';
            app.LectenZigzagButton = uibutton(app.CodageSoruceImageUIFigure, 'push');
            app.LectenZigzagButton.ButtonPushedFcn = createCallbackFcn(app, @LectenZigzagButtonPushed, true);
            app.LectenZigzagButton.Text = 'Lect en Zigzag';
            app.Thelongestsequenceof0obtainedLabel = uilabel(app.CodageSoruceImageUIFigure);
            app.Thelongestsequenceof0obtainedLabel.Text = 'The longest sequence of 0 obtained ';
            app.longestZerosSequenceText = uieditfield(app.CodageSoruceImageUIFigure, 'text');
            app.longestZerosSequenceText.Editable = 'off';
            app.TextArea = uitextarea(app.CodageSoruceImageUIFigure);
            app.TextArea.Editable = 'off';
            app.EnsaFes20222023AllrightsreservedLabel = uilabel(app.CodageSoruceImageUIFigure);
            app.EnsaFes20222023AllrightsreservedLabel.Text = 'Ensa Fes 2022/2023 All rights reserved';
            app.IlyasNhasseMohammedHandaLabel = uilabel(app.CodageSoruceImageUIFigure);
            app.IlyasNhasseMohammedHandaLabel.Text = '@Ilyas Nhasse @Mohammed Handa';
            % Show the figure after all components are created
            app.CodageSoruceImageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagePanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CodageSoruceImageUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CodageSoruceImageUIFigure)
        end
    end
end