classdef TextPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CodageSoruceTextUIFigure       matlab.ui.Figure
        IlyasNhasseMohammedHandaLabel  matlab.ui.control.Label
        EnsaFes20222023AllrightsreservedLabel  matlab.ui.control.Label
        EfficacityLabel                matlab.ui.control.Label
        EntropyLabel                   matlab.ui.control.Label
        MediumLengthLabel              matlab.ui.control.Label
        ResultLabel                    matlab.ui.control.Label
        ResultField                    matlab.ui.control.EditField
        LengthMoyHuffman               matlab.ui.control.EditField
        EntropyHuffman                 matlab.ui.control.NumericEditField
        ShanonFanoLabel                matlab.ui.control.Label
        HuffmanLabel                   matlab.ui.control.Label
        EfficacityShanonFano           matlab.ui.control.NumericEditField
        EntropyShanonFano              matlab.ui.control.NumericEditField
        EfficacityHuffman              matlab.ui.control.NumericEditField
        LengthMoyShanonFano            matlab.ui.control.EditField
        RapportCopressionShanonFano    matlab.ui.control.EditField
        RapportCopressionHuffman       matlab.ui.control.EditField
        BackButton                     matlab.ui.control.Button
        ENCODEButton                   matlab.ui.control.Button
        TextToCode                     matlab.ui.control.EditField
        InsertTextEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Home
            close(app.CodageSoruceTextUIFigure);
        end

        % Button pushed function: ENCODEButton
        function ENCODEButtonPushed(app, event)
            value = app.TextToCode.Value;
            Text = char(value);
            % HUFFMAN
            symboles = unique(Text);
            l = length(symboles);
            for i=1:l
                k = strfind(Text,symboles(i));
                n=length(k);
                pn(i)=n/length(Text);
            end
            symboles=double(symboles);
            m_code=huffmandict(symboles,pn);
            code2=huffmanenco(Text,m_code);
            % Taux de compression HUFFMAN
            taux_c=length(code2)/(length(Text)*8);
            app.RapportCopressionHuffman.Value=num2str(taux_c)+"%";
            %Longueur moyenne de huffman
            lmy = 0;
            for i=1:length(pn)
                lmy= lmy + pn(i)*length(m_code{i,2});
            end
            app.LengthMoyHuffman.Value = num2str(lmy);
            %Entropie huffman
            ETR = 0;
            for i=1:length(pn)
                ETR= ETR + pn(i)*log2(pn(i));
            end
            app.EntropyHuffman.Value = -ETR;
            %Efficacité huffman
            app.EfficacityHuffman.Value=-ETR/lmy*100;
            % shanon fano
            code=ShannonFanoCode(symboles,pn);
            m_code=EntropyEncoder(Text,code);
            m_code1=[];
            for i=1:length(m_code)
                m_code1=[m_code1 double(m_code{i})];
            end
            % taux de compression shanon fano
            taux_cS=length(m_code1)/(length(Text)*8);
            app.RapportCopressionShanonFano.Value=num2str(taux_cS)+"%";
            %Longueur moyenne de ShanonFano
            lmy1 = 0;
            for i=1:length(pn)
                lmy1= lmy1 + pn(i)*length(code{i,2});
            end
            app.LengthMoyShanonFano.Value = num2str(lmy1);
            %Entropie ShanonFano
            ETR1 = 0;
            for i=1:length(pn)
                ETR1= ETR1 + pn(i)*log2(pn(i));
            end
            app.EntropyShanonFano.Value = -ETR1;
            %Efficacité ShanonFano
            app.EfficacityShanonFano.Value=-ETR1/lmy1*100;
            if taux_c == taux_cS
                app.ResultField.Value="They are Equal";
            elseif taux_c > taux_cS
                app.ResultField.Value="Huffman is more Efficient";
                app.codedResult.Value=string(code2);
            else
                app.ResultField.Value="Shanon-fano is more Efficient";
                app.codedResult.Value=string(code);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CodageSoruceTextUIFigure = uifigure('Visible', 'off');
            app.CodageSoruceTextUIFigure.Name = 'Codage Soruce Text';
            app.InsertTextEditFieldLabel = uilabel(app.CodageSoruceTextUIFigure);
            app.InsertTextEditFieldLabel.Text = 'Insert Text';
            app.TextToCode = uieditfield(app.CodageSoruceTextUIFigure, 'text');
            app.ENCODEButton = uibutton(app.CodageSoruceTextUIFigure, 'push');
            app.ENCODEButton.ButtonPushedFcn = createCallbackFcn(app, @ENCODEButtonPushed, true);
            app.ENCODEButton.Text = 'ENCODE';
            app.BackButton = uibutton(app.CodageSoruceTextUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = 'icon1.png';
            app.BackButton.Text = 'Back';
            app.RapportCopressionHuffman = uieditfield(app.CodageSoruceTextUIFigure, 'text');
            app.RapportCopressionShanonFano = uieditfield(app.CodageSoruceTextUIFigure, 'text');
            app.LengthMoyShanonFano = uieditfield(app.CodageSoruceTextUIFigure, 'text');
            app.EfficacityHuffman = uieditfield(app.CodageSoruceTextUIFigure, 'numeric');
            app.EntropyShanonFano = uieditfield(app.CodageSoruceTextUIFigure, 'numeric');
            app.EfficacityShanonFano = uieditfield(app.CodageSoruceTextUIFigure, 'numeric');
            app.HuffmanLabel = uilabel(app.CodageSoruceTextUIFigure);
            app.HuffmanLabel.Text = 'Huffman';
            app.ShanonFanoLabel = uilabel(app.CodageSoruceTextUIFigure);
            app.ShanonFanoLabel.Text = 'Shanon Fano';
            app.EntropyHuffman = uieditfield(app.CodageSoruceTextUIFigure, 'numeric');
            app.LengthMoyHuffman = uieditfield(app.CodageSoruceTextUIFigure, 'text');
            app.ResultField = uieditfield(app.CodageSoruceTextUIFigure, 'text');
            app.ResultLabel = uilabel(app.CodageSoruceTextUIFigure);
            app.ResultLabel.Text = 'Result :';
            app.MediumLengthLabel = uilabel(app.CodageSoruceTextUIFigure);
            app.MediumLengthLabel.Text = 'Medium Length';
            app.EntropyLabel = uilabel(app.CodageSoruceTextUIFigure);
            app.EntropyLabel.Text = 'Entropy';
            app.EfficacityLabel = uilabel(app.CodageSoruceTextUIFigure);
            app.EfficacityLabel.Text = 'Efficacity';
            app.EnsaFes20222023AllrightsreservedLabel = uilabel(app.CodageSoruceTextUIFigure);
            app.EnsaFes20222023AllrightsreservedLabel.Text = 'Ensa Fes 2022/2023 All rights reserved';
            app.IlyasNhasseMohammedHandaLabel = uilabel(app.CodageSoruceTextUIFigure);
            app.IlyasNhasseMohammedHandaLabel.Text = '@Ilyas Nhasse @Mohammed Handa';
            % Show the figure after all components are created
            app.CodageSoruceTextUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TextPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CodageSoruceTextUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CodageSoruceTextUIFigure)
        end
    end
end